classdef ece480_final_project_VER_1_1_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ECE480ImageProcessingMidtermProjectPanel  matlab.ui.container.Panel
        UndoButton_2                    matlab.ui.control.Button
        RestartButton                   matlab.ui.control.Button
        TabGroup                        matlab.ui.container.TabGroup
        IntensityandSpatialFiltering    matlab.ui.container.Tab
        KernalFilteringPanel            matlab.ui.container.Panel
        lenEditField                    matlab.ui.control.EditField
        lenEditFieldLabel               matlab.ui.control.Label
        radiusEditField                 matlab.ui.control.EditField
        radiusEditFieldLabel            matlab.ui.control.Label
        alphaEditField                  matlab.ui.control.EditField
        alphaEditFieldLabel             matlab.ui.control.Label
        sigmaEditField                  matlab.ui.control.EditField
        sigmaEditFieldLabel             matlab.ui.control.Label
        thetaEditField                  matlab.ui.control.EditField
        thetaEditFieldLabel             matlab.ui.control.Label
        SobelButton                     matlab.ui.control.Button
        PrewittButton                   matlab.ui.control.Button
        MotionButton                    matlab.ui.control.Button
        LoGButton                       matlab.ui.control.Button
        DiskButton                      matlab.ui.control.Button
        AverageButton                   matlab.ui.control.Button
        hEditField                      matlab.ui.control.EditField
        hEditFieldLabel                 matlab.ui.control.Label
        LaplacianButton                 matlab.ui.control.Button
        GaussianButton                  matlab.ui.control.Button
        ContrastStretchingPanel         matlab.ui.container.Panel
        KmeanvalueCheckBox              matlab.ui.control.CheckBox
        KEditField                      matlab.ui.control.EditField
        KEditFieldLabel                 matlab.ui.control.Label
        EEditField                      matlab.ui.control.EditField
        EEditFieldLabel                 matlab.ui.control.Label
        FilterButton                    matlab.ui.control.Button
        SharpenPanel                    matlab.ui.container.Panel
        AmountEditField                 matlab.ui.control.EditField
        AmountEditFieldLabel            matlab.ui.control.Label
        RadiusEditField                 matlab.ui.control.EditField
        RadiusEditFieldLabel            matlab.ui.control.Label
        SharpenButton                   matlab.ui.control.Button
        LogTransformButton              matlab.ui.control.Button
        HistogramButton                 matlab.ui.control.Button
        FrequencyDomainFilteringTab     matlab.ui.container.Tab
        NotchFiltering                  matlab.ui.container.Panel
        NotchFIlterOrderEditField       matlab.ui.control.NumericEditField
        NotchFIlterOrderEditFieldLabel  matlab.ui.control.Label
        NotchRadiusEditField            matlab.ui.control.NumericEditField
        NotchRadiusEditFieldLabel       matlab.ui.control.Label
        PlaceNotchesButton              matlab.ui.control.Button
        ApplyFilterButton               matlab.ui.control.Button
        ClearAllButton                  matlab.ui.control.Button
        UndoButton                      matlab.ui.control.Button
        Notchfiltertype                 matlab.ui.container.ButtonGroup
        GaussianButton_3                matlab.ui.control.RadioButton
        ButterworthButton_2             matlab.ui.control.RadioButton
        IdealButton_2                   matlab.ui.control.RadioButton
        NotchType                       matlab.ui.container.ButtonGroup
        PassButton                      matlab.ui.control.RadioButton
        RejectButton                    matlab.ui.control.RadioButton
        ShowFFTPlotsSwitch              matlab.ui.control.Switch
        ShowFFTPlotsSwitchLabel         matlab.ui.control.Label
        PaddingMethodDropDown           matlab.ui.control.DropDown
        PaddingMethodDropDownLabel      matlab.ui.control.Label
        WidthoftheBandEditField         matlab.ui.control.NumericEditField
        WidthoftheBandEditFieldLabel    matlab.ui.control.Label
        CenterFrequencyEditField        matlab.ui.control.NumericEditField
        CenterFrequencyEditFieldLabel   matlab.ui.control.Label
        FilterOrderEditField            matlab.ui.control.NumericEditField
        FilterOrderEditFieldLabel       matlab.ui.control.Label
        CutoffFrequencyEditField        matlab.ui.control.NumericEditField
        CutoffFrequencyEditFieldLabel   matlab.ui.control.Label
        FilterTypeButtonGroup           matlab.ui.container.ButtonGroup
        BandrejectButton                matlab.ui.control.RadioButton
        BandpassButton                  matlab.ui.control.RadioButton
        HighpassButton                  matlab.ui.control.RadioButton
        LowpassButton                   matlab.ui.control.RadioButton
        GaussianButton_2                matlab.ui.control.Button
        IdealButton                     matlab.ui.control.Button
        ButterworthButton               matlab.ui.control.Button
        Transformations                 matlab.ui.container.Tab
        InterpolationMethodButtonGroup  matlab.ui.container.ButtonGroup
        BicubicButton                   matlab.ui.control.RadioButton
        BilinearButton                  matlab.ui.control.RadioButton
        NearestButton                   matlab.ui.control.RadioButton
        NoneButton                      matlab.ui.control.RadioButton
        HorizontalShearButton           matlab.ui.control.Button
        AlphaEditField                  matlab.ui.control.NumericEditField
        AlphaEditFieldLabel             matlab.ui.control.Label
        VerticalShearButton             matlab.ui.control.Button
        FlipHorizontalButton            matlab.ui.control.Button
        FlipVerticalButton              matlab.ui.control.Button
        RotateImageButton               matlab.ui.control.Button
        DegreesEditField                matlab.ui.control.NumericEditField
        DegreesEditFieldLabel           matlab.ui.control.Label
        YTranslateEditField             matlab.ui.control.NumericEditField
        YTranslateEditFieldLabel        matlab.ui.control.Label
        XTranslateEditField             matlab.ui.control.NumericEditField
        XTranslateEditFieldLabel        matlab.ui.control.Label
        TranslateImageButton            matlab.ui.control.Button
        ScaleImageButton                matlab.ui.control.Button
        YScalingEditField               matlab.ui.control.NumericEditField
        YScalingEditFieldLabel          matlab.ui.control.Label
        XScalingEditField               matlab.ui.control.NumericEditField
        XScalingEditFieldLabel          matlab.ui.control.Label
        OrignalPanel                    matlab.ui.container.Panel
        orignal                         matlab.ui.control.Image
        FilteredImagePanel              matlab.ui.container.Panel
        filtered                        matlab.ui.control.Image
        FileInformationPanel            matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        FileDetailsButton               matlab.ui.control.Button
        SelectImageButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        image_file_name % Description
        image_info
        kmean
        k_value
        e_value
        image_color
        save_as
        log_image
        hist_image
        gs
        current_image
        done
        im
        gauss_image
        lap_image
        avg_image
        disk_image
        lapofgauss_image
        sobel_image
        prewitt_image
        motion_image
        alpha
        theta
        h
        sigma
        radius
        len
        sharpen_rad
        amount
        sharp_image
        image_array
        D0
        n
        C0
        W
        Ideal_image
        Butterworth_image
        Gaussian_image
        padMethod
        FFTplot_bool
        Notch_image
        Notch_transfer
        notch_filter_order
        notch_radius
        centers
        radii
        fft
        at_least_one
        fig
        x_scale
        y_scale
        x_trans
        y_trans
        degrees
        alpha_shear
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
           filterspec = {'*.jpg;*.tif;*.png;*.gif','All Image Files'}
           [filename,filepath] = uigetfile(filterspec);
           fullname = [filepath, filename];
           app.image_file_name=filename;
           app.image_info=imread(filename);
         %  app.image_info=rgb2gray(app.image_color);
           app.orignal.ImageSource = filename;
           app.done=0
           app.image_array = [];
           app.image_array = cat(3,app.image_array,app.image_info);
        end

        % Button pushed function: FileDetailsButton
        function FileDetailsButtonPushed(app, event)
            imageinfo(app.image_file_name)
        end

        % Button pushed function: HistogramButton
        function HistogramButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.hist_image=histeq(app.im)
            app.filtered.ImageSource=app.hist_image
            app.current_image=app.hist_image
            app.image_array = cat(3, app.image_array, app.hist_image);
            app.done=1
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            E=app.e_value
            if app.done==0;
                app.im=app.image_info
            else
                app.im=app.current_image
            end
            if app.kmean==1
                k=mean2(double(app.im))
            else 
                k=app.k_value
            end
            gam=1./(1+(k./double(app.im)).^E);
            app.gs=im2uint8(mat2gray(gam));
            app.filtered.ImageSource=app.gs;
            app.current_image=app.gs;
            app.image_array = cat(3, app.image_array, app.gs);
            app.done=1;
        end

        % Value changed function: KmeanvalueCheckBox
        function KmeanvalueCheckBoxValueChanged(app, event)
            value = app.KmeanvalueCheckBox.Value;
            app.kmean=value;
        end

        % Value changing function: EEditField
        function EEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changing function: KEditField
        function KEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Value changed function: EEditField
        function EEditFieldValueChanged(app, event)
            value = app.EEditField.Value;
            app.e_value=str2num(value);
        end

        % Value changed function: KEditField
        function KEditFieldValueChanged(app, event)
            value = app.KEditField.Value;
            app.k_value=str2num(value);
        end

        % Button pushed function: LogTransformButton
        function LogTransformButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info
            else
                app.im=app.current_image
            end
            g=log(1+double(app.im));
            app.log_image=im2uint8(mat2gray(g));
            app.filtered.ImageSource=app.log_image;
            app.current_image=app.log_image;
            app.image_array = cat(3, app.image_array, app.log_image);
            app.done=1;
            
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            [filename, pathname]=uiputfile({'*.jpg','*.png'});
            newfilename=fullfile(pathname, filename)
            figure
            imshow(app.filtered.ImageSource)
            saveas(gcf, newfilename)
            close all
        end

        % Button pushed function: RestartButton
        function RestartButtonPushed(app, event)
            app.filtered.ImageSource=app.image_info;
            app.current_image=app.image_info;
        end

        % Button pushed function: GaussianButton
        function GaussianButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.gauss_image=imfilter(app.im, fspecial('gaussian', app.h, app.sigma))
            app.filtered.ImageSource=app.gauss_image
            app.current_image=app.gauss_image
            app.image_array = cat(3, app.image_array, app.gauss_image);
            app.done=1
        end

        % Button pushed function: LaplacianButton
        function LaplacianButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.lap_image=imfilter(app.im, fspecial('laplacian', app.alpha))
            app.filtered.ImageSource=app.lap_image
            app.current_image=app.lap_image
            app.image_array = cat(3, app.image_array, app.lap_image);
            app.done=1
        end

        % Button pushed function: AverageButton
        function AverageButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.avg_image=imfilter(app.im, fspecial('average', app.h))
            app.filtered.ImageSource=app.avg_image
            app.current_image=app.avg_image
            app.image_array = cat(3, app.image_array, app.avg_image);
            app.done=1
        end

        % Button pushed function: DiskButton
        function DiskButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
             end
             app.radius
            app.disk_image=imfilter(app.im, fspecial('disk', app.radius))
            app.filtered.ImageSource=app.disk_image
            app.current_image=app.disk_image
            app.image_array = cat(3, app.image_array, app.disk_image);
            app.done=1
        end

        % Button pushed function: LoGButton
        function LoGButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.lapofgauss_image=imfilter(app.im, fspecial('log', app.h, app.sigma))
            app.filtered.ImageSource=app.lapofgauss_image
            app.current_image=app.lapofgauss_image
            app.image_array = cat(3, app.image_array, app.lapofgauss_image);
            app.done=1
        end

        % Button pushed function: SobelButton
        function SobelButtonPushed(app, event)
             if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.sobel_image=imfilter(app.im, fspecial('sobel'))
            app.filtered.ImageSource=app.sobel_image
            app.current_image=app.sobel_image
            app.image_array = cat(3, app.image_array, app.sobel_image);
            app.done=1
        end

        % Button pushed function: PrewittButton
        function PrewittButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.prewitt_image=imfilter(app.im, fspecial('prewitt'))
            app.filtered.ImageSource=app.prewitt_image
            app.current_image=app.prewitt_image
            app.image_array = cat(3, app.image_array, app.prewitt_image);
            app.done=1
        end

        % Button pushed function: MotionButton
        function MotionButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.motion_image=imfilter(app.im, fspecial('motion', app.len, app.theta))
            app.filtered.ImageSource=app.motion_image
            app.current_image=app.motion_image
            app.image_array = cat(3, app.image_array, app.motion_image);
            app.done=1
        end

        % Value changed function: alphaEditField
        function alphaEditFieldValueChanged(app, event)
            value = app.alphaEditField.Value;
            app.alpha=str2double(value)
        end

        % Value changed function: sigmaEditField
        function sigmaEditFieldValueChanged(app, event)
            value = app.sigmaEditField.Value;
            app.sigma=str2double(value)
        end

        % Value changed function: hEditField
        function hEditFieldValueChanged(app, event)
            value = app.hEditField.Value;
            app.h=str2double(value)
        end

        % Value changed function: thetaEditField
        function thetaEditFieldValueChanged(app, event)
            value = app.thetaEditField.Value;
            app.theta=str2double(value)
        end

        % Value changed function: radiusEditField
        function radiusEditFieldValueChanged(app, event)
            value = app.radiusEditField.Value;
            app.radius=str2double(value)
        end

        % Value changed function: lenEditField
        function lenEditFieldValueChanged(app, event)
            value = app.lenEditField.Value;
            app.len=str2double(value)
        end

        % Button pushed function: SharpenButton
        function SharpenButtonPushed(app, event)
            if app.done==0;
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.sharp_image=imsharpen(app.im, Radius=app.sharpen_rad, Amount=app.amount)
            app.filtered.ImageSource=app.sharp_image
            app.current_image=app.sharp_image
            app.image_array = cat(3, app.image_array, app.sharp_image);
            app.done=1
        end

        % Value changed function: AmountEditField
        function AmountEditFieldValueChanged(app, event)
            value = app.AmountEditField.Value;
            app.amount=str2double(value)
        end

        % Value changed function: RadiusEditField
        function RadiusEditFieldValueChanged(app, event)
            value = app.RadiusEditField.Value;
            app.sharpen_rad=str2double(value)
        end

        % Value changed function: PaddingMethodDropDown
        function PaddingMethodDropDownValueChanged(app, event)
            value = app.PaddingMethodDropDown.Value;
            app.padMethod = value;
        end

        % Value changed function: NotchFIlterOrderEditField
        function NotchFIlterOrderEditFieldValueChanged(app, event)
            value = app.NotchFIlterOrderEditField.Value;
            app.notch_filter_order = value;
        end

        % Value changed function: NotchRadiusEditField
        function NotchRadiusEditFieldValueChanged(app, event)
            value = app.NotchRadiusEditField.Value;
            app.notch_radius = value;
        end

        % Button pushed function: PlaceNotchesButton
        function PlaceNotchesButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            if app.GaussianButton_3.Value == 1
                filter_type = 'gaussian';
            elseif app.ButterworthButton_2.Value == 1
                filter_type = 'butterworth';
            else
                filter_type = 'ideal';
            end
            
            if app.PassButton.Value == 1
                notch_type = 'pass';
            else
                notch_type = 'reject';
            end

            app.Notchfiltertype.Enable = "off";
            app.NotchType.Enable = "off";
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');
            fp = padarray(app.im, [PQ(1)-M(1), PQ(2)-M(2)], 'post');
            
            F = fft2(fp);
            Fc = fftshift(F);
            
            app.fft = mat2gray(1 + log(abs(Fc)));
            if app.at_least_one == false
                figure;
            end

            while true
                if isempty(app.centers) || isempty(app.radii)
                    imshow(app.fft); title('Image FFT with Notch Filtering')
                else
                    H = notchfilter(filter_type, notch_type, PQ(1), PQ(2), app.centers, app.radii, app.notch_filter_order);
                    Hc = fftshift(H);
                    S1 = (Hc.*app.fft);
                    imshow(S1); title('Image FFT with Notch Filtering')
                    app.Notch_transfer = H;
                end
                
                C_in = ginput(1);
                if not(isempty(C_in))
                    C_in(1) = PQ(1)/2 - C_in(1);
                    C_in(2) = PQ(2)/2 - C_in(2);
                    
                    v = C_in(1);
                    C_in(1) = C_in(2);
                    C_in(2) = v;
                
                    app.centers = cat(1, app.centers, round(C_in));
                    app.radii = cat(1, app.radii, app.notch_radius);
                    app.at_least_one = true;
                else
                    break
                end
            end
        end

        % Button pushed function: ApplyFilterButton
        function ApplyFilterButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.Notch_image = dftfilt(app.im, app.Notch_transfer, app.padMethod);
            app.filtered.ImageSource = app.Notch_image;
            close all
            if app.FFTplot_bool == 1
                showfftplots(app.im, app.Notch_transfer, app.padMethod);
            end
            app.current_image = app.Notch_image;
            app.image_array = cat(3, app.image_array, app.Notch_image);
            app.done = 1;
            
            app.centers = [];
            app.radii = [];
            app.Notch_transfer = [];
            app.Notchfiltertype.Enable = "on";
            app.NotchType.Enable = "on";
            app.at_least_one = false;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
            app.centers = [];
            app.radii = [];
            app.Notch_transfer = [];
            imshow(app.fft); title('Image FFT with Notch Filtering')
        end

        % Button pushed function: UndoButton
        function UndoButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            app.centers(end,:) = [];
            app.radii(end,:) = [];
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.GaussianButton_3.Value == 1
                filter_type = 'gaussian';
            elseif app.ButterworthButton_2.Value == 1
                filter_type = 'butterworth';
            else
                filter_type = 'ideal';
            end
            
            if app.PassButton.Value == 1
                notch_type = 'pass';
            else
                notch_type = 'reject';
            end
            
            if isempty(app.centers) || isempty(app.radii)
                imshow(app.fft); title('Image FFT with Notch Filtering')
            else
                H = notchfilter(filter_type, notch_type, PQ(1), PQ(2), app.centers, app.radii, app.notch_filter_order);
                Hc = fftshift(H);
                S1 = (Hc.*app.fft);
                imshow(S1); title('Image FFT with Notch Filtering')
                app.Notch_transfer = H;
            end
        end

        % Value changed function: ShowFFTPlotsSwitch
        function ShowFFTPlotsSwitchValueChanged(app, event)
            value = app.ShowFFTPlotsSwitch.Value;
            app.FFTplot_bool = value;
        end

        % Value changed function: WidthoftheBandEditField
        function WidthoftheBandEditFieldValueChanged(app, event)
            value = app.WidthoftheBandEditField.Value;
            app.W = value;
        end

        % Value changed function: CenterFrequencyEditField
        function CenterFrequencyEditFieldValueChanged(app, event)
            value = app.CenterFrequencyEditField.Value;
            app.C0 = value;
        end

        % Value changed function: FilterOrderEditField
        function FilterOrderEditFieldValueChanged(app, event)
            value = app.FilterOrderEditField.Value;
            app.n = value;
        end

        % Value changed function: CutoffFrequencyEditField
        function CutoffFrequencyEditFieldValueChanged(app, event)
            value = app.CutoffFrequencyEditField.Value;
            app.D0 = value;
        end

        % Button pushed function: GaussianButton_2
        function GaussianButton_2Pushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end

            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('gaussian', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('gaussian', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('gaussian', 'pass', PQ(1), PQ(2), app.C0, app.W);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('gaussian', 'reject', PQ(1), PQ(2), app.C0, app.W);
            end

            app.Gaussian_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Gaussian_image;
            app.current_image = app.Gaussian_image;
            app.image_array = cat(3, app.image_array, app.Gaussian_image);
            app.done = 1;
        end

        % Button pushed function: IdealButton
        function IdealButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('ideal', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('ideal', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('ideal', 'pass', PQ(1), PQ(2), app.C0, app.W);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('ideal', 'reject', PQ(1), PQ(2), app.C0, app.W);
            end

            app.Ideal_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Ideal_image;
            app.current_image = app.Ideal_image;
            app.image_array = cat(3, app.image_array, app.Ideal_image);
            app.done = 1;
        end

        % Button pushed function: ButterworthButton
        function ButterworthButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            M = size(app.im);
            PQ = paddedsize([M(1), M(2)], 'pwr2');

            if app.LowpassButton.Value == 1
                H = lpfilter('butterworth', PQ(1),PQ(2),app.D0);
            elseif app.HighpassButton.Value == 1
                H = hpfilter('butterworth', PQ(1),PQ(2),app.D0);
            elseif app.BandpassButton.Value == 1
                H = bandfilter('butterworth', 'pass', PQ(1), PQ(2), app.C0, app.W, app.n);
            elseif app.BandrejectButton.Value == 1
                H = bandfilter('butterworth', 'reject', PQ(1), PQ(2), app.C0, app.W, app.n);
            end

            app.Butterworth_image = dftfilt(app.im, H, app.padMethod);
            if app.FFTplot_bool == 1
                showfftplots(app.im, H, app.padMethod);
            end
            app.filtered.ImageSource = app.Butterworth_image;
            app.current_image = app.Butterworth_image;
            app.image_array = cat(3, app.image_array, app.Butterworth_image);
            app.done = 1;
        end

        % Button pushed function: UndoButton_2
        function UndoButton_2Pushed(app, event)
            if not(isempty(app.image_array))
                app.image_array(:,:,end-2:end) = [];
                if isempty(app.image_array)
                    app.filtered.ImageSource = app.image_info;
                    app.current_image = app.image_info;
                else
                    app.filtered.ImageSource = app.image_array(:,:,end-2:end);
                    app.current_image = app.image_array(:,:,end-2:end);
                end
            end
            app.done = 1;
        end

        % Value changed function: XScalingEditField
        function XScalingEditFieldValueChanged(app, event)
            value = app.XScalingEditField.Value;
            app.x_scale = value;
        end

        % Value changed function: YScalingEditField
        function YScalingEditFieldValueChanged(app, event)
            value = app.YScalingEditField.Value;
            app.y_scale = value;
        end

        % Value changed function: XTranslateEditField
        function XTranslateEditFieldValueChanged(app, event)
            value = app.XTranslateEditField.Value;
            app.x_trans = value;
        end

        % Value changed function: YTranslateEditField
        function YTranslateEditFieldValueChanged(app, event)
            value = app.YTranslateEditField.Value;
            app.y_trans = value;
        end

        % Callback function: not associated with a component
        function InterpolationMethodDropDownValueChanged(app, event)
            value = app.InterpolationMethodDropDown.Value;
            app.interp_method = value;
        end

        % Value changed function: DegreesEditField
        function DegreesEditFieldValueChanged(app, event)
            value = app.DegreesEditField.Value;
            app.degrees = value;
        end

        % Value changed function: AlphaEditField
        function AlphaEditFieldValueChanged(app, event)
            value = app.AlphaEditField.Value;
            app.alpha_shear = value;
        end

        % Button pushed function: ScaleImageButton
        function ScaleImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('scale', app.x_scale, app.y_scale);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
            
        end

        % Button pushed function: TranslateImageButton
        function TranslateImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('translate', app.x_trans, app.y_trans);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end

        % Button pushed function: RotateImageButton
        function RotateImageButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('rotate', app.degrees);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end

        % Button pushed function: FlipVerticalButton
        function FlipVerticalButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('v-reflect');

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end

        % Button pushed function: FlipHorizontalButton
        function FlipHorizontalButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('h-reflect');

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end

        % Button pushed function: VerticalShearButton
        function VerticalShearButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('v-shear',app.alpha_shear);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end

        % Button pushed function: HorizontalShearButton
        function HorizontalShearButtonPushed(app, event)
            if app.done==0
                app.im=app.image_info;
            else
                app.im=app.current_image;
            end
            
            if ndims(app.im) == 3
                isRGB = 1;
                f = app.im(:,:,1);
            else
                isRGB = 0;
                f = app.im;
            end
            
            tform = geotrans('h-shear',app.alpha_shear);

            if (app.NearestButton.Value == 1)
                g = imwarp(f, tform, 'nearest');
            elseif (app.BilinearButton == 1)
                g = imwarp(f, tform, 'bilinear');
            elseif (app.BicubicButton == 1)
                g = imwarp(f, tform, 'bicubic');
            else
                g = imwarp(f, tform);
            end

            if isRGB == 1
                g1 = repmat(g, [1 1 3]);
            end

            app.filtered.ImageSource = g1;
            app.current_image = g1;
%             app.image_array = cat(3, app.image_array, g);
            app.done = 1;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ECE480ImageProcessingMidtermProjectPanel = uipanel(app.UIFigure);
            app.ECE480ImageProcessingMidtermProjectPanel.Title = 'ECE 480 Image Processing Midterm Project';
            app.FileInformationPanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.FileInformationPanel.Title = 'File Information';
            app.SelectImageButton = uibutton(app.FileInformationPanel, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.FileDetailsButton = uibutton(app.FileInformationPanel, 'push');
            app.FileDetailsButton.ButtonPushedFcn = createCallbackFcn(app, @FileDetailsButtonPushed, true);
            app.FileDetailsButton.Text = 'File Details';
            app.SaveButton = uibutton(app.FileInformationPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.FilteredImagePanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.FilteredImagePanel.Title = 'Filtered Image';
            app.filtered = uiimage(app.FilteredImagePanel);
            app.OrignalPanel = uipanel(app.ECE480ImageProcessingMidtermProjectPanel);
            app.OrignalPanel.Title = 'Orignal';
            app.orignal = uiimage(app.OrignalPanel);
            app.TabGroup = uitabgroup(app.ECE480ImageProcessingMidtermProjectPanel);
            app.IntensityandSpatialFiltering = uitab(app.TabGroup);
            app.IntensityandSpatialFiltering.Title = 'Intensity and Spatial Filtering';
            app.HistogramButton = uibutton(app.IntensityandSpatialFiltering, 'push');
            app.HistogramButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramButtonPushed, true);
            app.HistogramButton.Text = 'Histogram';
            app.LogTransformButton = uibutton(app.IntensityandSpatialFiltering, 'push');
            app.LogTransformButton.ButtonPushedFcn = createCallbackFcn(app, @LogTransformButtonPushed, true);
            app.LogTransformButton.Text = 'Log Transform';
            app.SharpenPanel = uipanel(app.IntensityandSpatialFiltering);
            app.SharpenPanel.Title = 'Sharpen';
            app.SharpenButton = uibutton(app.SharpenPanel, 'push');
            app.SharpenButton.ButtonPushedFcn = createCallbackFcn(app, @SharpenButtonPushed, true);
            app.SharpenButton.Text = 'Sharpen';
            app.RadiusEditFieldLabel = uilabel(app.SharpenPanel);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.SharpenPanel, 'text');
            app.RadiusEditField.ValueChangedFcn = createCallbackFcn(app, @RadiusEditFieldValueChanged, true);
            app.AmountEditFieldLabel = uilabel(app.SharpenPanel);
            app.AmountEditFieldLabel.Text = 'Amount';
            app.AmountEditField = uieditfield(app.SharpenPanel, 'text');
            app.AmountEditField.ValueChangedFcn = createCallbackFcn(app, @AmountEditFieldValueChanged, true);
            app.ContrastStretchingPanel = uipanel(app.IntensityandSpatialFiltering);
            app.ContrastStretchingPanel.Title = 'Contrast Stretching';
            app.FilterButton = uibutton(app.ContrastStretchingPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.EEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.EEditFieldLabel.Text = 'E';
            app.EEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.EEditField.ValueChangedFcn = createCallbackFcn(app, @EEditFieldValueChanged, true);
            app.EEditField.ValueChangingFcn = createCallbackFcn(app, @EEditFieldValueChanging, true);
            app.KEditFieldLabel = uilabel(app.ContrastStretchingPanel);
            app.KEditFieldLabel.Text = 'K';
            app.KEditField = uieditfield(app.ContrastStretchingPanel, 'text');
            app.KEditField.ValueChangedFcn = createCallbackFcn(app, @KEditFieldValueChanged, true);
            app.KEditField.ValueChangingFcn = createCallbackFcn(app, @KEditFieldValueChanging, true);
            app.KmeanvalueCheckBox = uicheckbox(app.ContrastStretchingPanel);
            app.KmeanvalueCheckBox.ValueChangedFcn = createCallbackFcn(app, @KmeanvalueCheckBoxValueChanged, true);
            app.KmeanvalueCheckBox.Text = 'K mean value';
            app.KernalFilteringPanel = uipanel(app.IntensityandSpatialFiltering);
            app.KernalFilteringPanel.Title = 'Kernal Filtering';
            app.GaussianButton = uibutton(app.KernalFilteringPanel, 'push');
            app.GaussianButton.ButtonPushedFcn = createCallbackFcn(app, @GaussianButtonPushed, true);
            app.GaussianButton.Text = 'Gaussian';
            app.LaplacianButton = uibutton(app.KernalFilteringPanel, 'push');
            app.LaplacianButton.ButtonPushedFcn = createCallbackFcn(app, @LaplacianButtonPushed, true);
            app.LaplacianButton.Text = 'Laplacian';
            app.hEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.hEditFieldLabel.Text = 'h';
            app.hEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.hEditField.ValueChangedFcn = createCallbackFcn(app, @hEditFieldValueChanged, true);
            app.AverageButton = uibutton(app.KernalFilteringPanel, 'push');
            app.AverageButton.ButtonPushedFcn = createCallbackFcn(app, @AverageButtonPushed, true);
            app.AverageButton.Text = 'Average';
            app.DiskButton = uibutton(app.KernalFilteringPanel, 'push');
            app.DiskButton.ButtonPushedFcn = createCallbackFcn(app, @DiskButtonPushed, true);
            app.DiskButton.Text = 'Disk';
            app.LoGButton = uibutton(app.KernalFilteringPanel, 'push');
            app.LoGButton.ButtonPushedFcn = createCallbackFcn(app, @LoGButtonPushed, true);
            app.LoGButton.Text = 'LoG';
            app.MotionButton = uibutton(app.KernalFilteringPanel, 'push');
            app.MotionButton.ButtonPushedFcn = createCallbackFcn(app, @MotionButtonPushed, true);
            app.MotionButton.Text = 'Motion';
            app.PrewittButton = uibutton(app.KernalFilteringPanel, 'push');
            app.PrewittButton.ButtonPushedFcn = createCallbackFcn(app, @PrewittButtonPushed, true);
            app.PrewittButton.Text = 'Prewitt';
            app.SobelButton = uibutton(app.KernalFilteringPanel, 'push');
            app.SobelButton.ButtonPushedFcn = createCallbackFcn(app, @SobelButtonPushed, true);
            app.SobelButton.Text = 'Sobel';
            app.thetaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.thetaEditField.ValueChangedFcn = createCallbackFcn(app, @thetaEditFieldValueChanged, true);
            app.sigmaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.sigmaEditFieldLabel.Text = 'sigma';
            app.sigmaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.sigmaEditField.ValueChangedFcn = createCallbackFcn(app, @sigmaEditFieldValueChanged, true);
            app.alphaEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.alphaEditFieldLabel.Text = 'alpha';
            app.alphaEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.alphaEditField.ValueChangedFcn = createCallbackFcn(app, @alphaEditFieldValueChanged, true);
            app.radiusEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.radiusEditFieldLabel.Text = 'radius';
            app.radiusEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.radiusEditField.ValueChangedFcn = createCallbackFcn(app, @radiusEditFieldValueChanged, true);
            app.lenEditFieldLabel = uilabel(app.KernalFilteringPanel);
            app.lenEditFieldLabel.Text = 'len';
            app.lenEditField = uieditfield(app.KernalFilteringPanel, 'text');
            app.lenEditField.ValueChangedFcn = createCallbackFcn(app, @lenEditFieldValueChanged, true);
            app.FrequencyDomainFilteringTab = uitab(app.TabGroup);
            app.FrequencyDomainFilteringTab.Title = 'Frequency Domain Filtering';
            app.ButterworthButton = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.ButterworthButton.ButtonPushedFcn = createCallbackFcn(app, @ButterworthButtonPushed, true);
            app.ButterworthButton.Text = 'Butterworth';
            app.IdealButton = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.IdealButton.ButtonPushedFcn = createCallbackFcn(app, @IdealButtonPushed, true);
            app.IdealButton.Text = 'Ideal';
            app.GaussianButton_2 = uibutton(app.FrequencyDomainFilteringTab, 'push');
            app.GaussianButton_2.ButtonPushedFcn = createCallbackFcn(app, @GaussianButton_2Pushed, true);
            app.GaussianButton_2.Text = 'Gaussian';
            app.FilterTypeButtonGroup = uibuttongroup(app.FrequencyDomainFilteringTab);
            app.FilterTypeButtonGroup.Title = 'Filter Type';
            app.LowpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.LowpassButton.Text = 'Lowpass';
            app.LowpassButton.Value = true;
            app.HighpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.HighpassButton.Text = 'Highpass';
            app.BandpassButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandpassButton.Text = 'Bandpass';
            app.BandrejectButton = uiradiobutton(app.FilterTypeButtonGroup);
            app.BandrejectButton.Text = 'Bandreject';
            app.CutoffFrequencyEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.CutoffFrequencyEditFieldLabel.Text = 'Cutoff Frequency';
            app.CutoffFrequencyEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.CutoffFrequencyEditField.Limits = [0 Inf];
            app.CutoffFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyEditFieldValueChanged, true);
            app.FilterOrderEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.FilterOrderEditFieldLabel.Text = 'Filter Order';
            app.FilterOrderEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.FilterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @FilterOrderEditFieldValueChanged, true);
            app.CenterFrequencyEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.CenterFrequencyEditFieldLabel.Text = 'Center Frequency';
            app.CenterFrequencyEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.CenterFrequencyEditField.ValueChangedFcn = createCallbackFcn(app, @CenterFrequencyEditFieldValueChanged, true);
            app.WidthoftheBandEditFieldLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.WidthoftheBandEditFieldLabel.Text = 'Width of the Band';
            app.WidthoftheBandEditField = uieditfield(app.FrequencyDomainFilteringTab, 'numeric');
            app.WidthoftheBandEditField.ValueChangedFcn = createCallbackFcn(app, @WidthoftheBandEditFieldValueChanged, true);
            app.PaddingMethodDropDownLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.PaddingMethodDropDownLabel.Text = 'Padding Method';
            app.PaddingMethodDropDown = uidropdown(app.FrequencyDomainFilteringTab);
            app.PaddingMethodDropDown.Items = {'symmetric', 'zeros', 'replicate', 'circular'};
            app.PaddingMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @PaddingMethodDropDownValueChanged, true);
            app.PaddingMethodDropDown.Value = 'symmetric';
            app.ShowFFTPlotsSwitchLabel = uilabel(app.FrequencyDomainFilteringTab);
            app.ShowFFTPlotsSwitchLabel.Text = 'Show FFT Plots';
            app.ShowFFTPlotsSwitch = uiswitch(app.FrequencyDomainFilteringTab, 'slider');
            app.ShowFFTPlotsSwitch.ItemsData = [0 1];
            app.ShowFFTPlotsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowFFTPlotsSwitchValueChanged, true);
            app.ShowFFTPlotsSwitch.Value = 0;
            app.NotchFiltering = uipanel(app.FrequencyDomainFilteringTab);
            app.NotchFiltering.Title = 'Notch Filtering';
            app.NotchType = uibuttongroup(app.NotchFiltering);
            app.NotchType.Title = 'Notch Type';
            app.RejectButton = uiradiobutton(app.NotchType);
            app.RejectButton.Text = 'Reject';
            app.RejectButton.Value = true;
            app.PassButton = uiradiobutton(app.NotchType);
            app.PassButton.Text = 'Pass';
            app.Notchfiltertype = uibuttongroup(app.NotchFiltering);
            app.Notchfiltertype.Title = 'Notch FIlter Type';
            app.IdealButton_2 = uiradiobutton(app.Notchfiltertype);
            app.IdealButton_2.Text = 'Ideal';
            app.IdealButton_2.Value = true;
            app.ButterworthButton_2 = uiradiobutton(app.Notchfiltertype);
            app.ButterworthButton_2.Text = 'Butterworth';
            app.GaussianButton_3 = uiradiobutton(app.Notchfiltertype);
            app.GaussianButton_3.Text = 'Gaussian';
            app.UndoButton = uibutton(app.NotchFiltering, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonPushed, true);
            app.UndoButton.Text = 'Undo';
            app.ClearAllButton = uibutton(app.NotchFiltering, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.ApplyFilterButton = uibutton(app.NotchFiltering, 'push');
            app.ApplyFilterButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyFilterButtonPushed, true);
            app.ApplyFilterButton.Text = 'Apply Filter';
            app.PlaceNotchesButton = uibutton(app.NotchFiltering, 'push');
            app.PlaceNotchesButton.ButtonPushedFcn = createCallbackFcn(app, @PlaceNotchesButtonPushed, true);
            app.PlaceNotchesButton.Text = 'Place Notches';
            app.NotchRadiusEditFieldLabel = uilabel(app.NotchFiltering);
            app.NotchRadiusEditFieldLabel.Text = 'Notch Radius';
            app.NotchRadiusEditField = uieditfield(app.NotchFiltering, 'numeric');
            app.NotchRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @NotchRadiusEditFieldValueChanged, true);
            app.NotchFIlterOrderEditFieldLabel = uilabel(app.NotchFiltering);
            app.NotchFIlterOrderEditFieldLabel.Text = 'Notch FIlter Order';
            app.NotchFIlterOrderEditField = uieditfield(app.NotchFiltering, 'numeric');
            app.NotchFIlterOrderEditField.ValueChangedFcn = createCallbackFcn(app, @NotchFIlterOrderEditFieldValueChanged, true);
            app.Transformations = uitab(app.TabGroup);
            app.Transformations.Title = 'Transformations';
            app.XScalingEditFieldLabel = uilabel(app.Transformations);
            app.XScalingEditFieldLabel.Text = 'X Scaling';
            app.XScalingEditField = uieditfield(app.Transformations, 'numeric');
            app.XScalingEditField.ValueChangedFcn = createCallbackFcn(app, @XScalingEditFieldValueChanged, true);
            app.XScalingEditField.Value = 1;
            app.YScalingEditFieldLabel = uilabel(app.Transformations);
            app.YScalingEditFieldLabel.Text = 'Y Scaling';
            app.YScalingEditField = uieditfield(app.Transformations, 'numeric');
            app.YScalingEditField.ValueChangedFcn = createCallbackFcn(app, @YScalingEditFieldValueChanged, true);
            app.YScalingEditField.Value = 1;
            app.ScaleImageButton = uibutton(app.Transformations, 'push');
            app.ScaleImageButton.ButtonPushedFcn = createCallbackFcn(app, @ScaleImageButtonPushed, true);
            app.ScaleImageButton.Text = 'Scale Image';
            app.TranslateImageButton = uibutton(app.Transformations, 'push');
            app.TranslateImageButton.ButtonPushedFcn = createCallbackFcn(app, @TranslateImageButtonPushed, true);
            app.TranslateImageButton.Text = 'Translate Image';
            app.XTranslateEditFieldLabel = uilabel(app.Transformations);
            app.XTranslateEditFieldLabel.Text = 'X Translate';
            app.XTranslateEditField = uieditfield(app.Transformations, 'numeric');
            app.XTranslateEditField.ValueChangedFcn = createCallbackFcn(app, @XTranslateEditFieldValueChanged, true);
            app.YTranslateEditFieldLabel = uilabel(app.Transformations);
            app.YTranslateEditFieldLabel.Text = 'Y Translate';
            app.YTranslateEditField = uieditfield(app.Transformations, 'numeric');
            app.YTranslateEditField.ValueChangedFcn = createCallbackFcn(app, @YTranslateEditFieldValueChanged, true);
            app.DegreesEditFieldLabel = uilabel(app.Transformations);
            app.DegreesEditFieldLabel.Text = 'Degrees';
            app.DegreesEditField = uieditfield(app.Transformations, 'numeric');
            app.DegreesEditField.ValueChangedFcn = createCallbackFcn(app, @DegreesEditFieldValueChanged, true);
            app.RotateImageButton = uibutton(app.Transformations, 'push');
            app.RotateImageButton.ButtonPushedFcn = createCallbackFcn(app, @RotateImageButtonPushed, true);
            app.RotateImageButton.Text = 'Rotate Image';
            app.FlipVerticalButton = uibutton(app.Transformations, 'push');
            app.FlipVerticalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipVerticalButtonPushed, true);
            app.FlipVerticalButton.Text = 'Flip Vertical';
            app.FlipHorizontalButton = uibutton(app.Transformations, 'push');
            app.FlipHorizontalButton.ButtonPushedFcn = createCallbackFcn(app, @FlipHorizontalButtonPushed, true);
            app.FlipHorizontalButton.Text = 'Flip Horizontal';
            app.VerticalShearButton = uibutton(app.Transformations, 'push');
            app.VerticalShearButton.ButtonPushedFcn = createCallbackFcn(app, @VerticalShearButtonPushed, true);
            app.VerticalShearButton.Text = 'Vertical Shear';
            app.AlphaEditFieldLabel = uilabel(app.Transformations);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.Transformations, 'numeric');
            app.AlphaEditField.ValueChangedFcn = createCallbackFcn(app, @AlphaEditFieldValueChanged, true);
            app.HorizontalShearButton = uibutton(app.Transformations, 'push');
            app.HorizontalShearButton.ButtonPushedFcn = createCallbackFcn(app, @HorizontalShearButtonPushed, true);
            app.HorizontalShearButton.Text = 'Horizontal Shear';
            app.InterpolationMethodButtonGroup = uibuttongroup(app.Transformations);
            app.InterpolationMethodButtonGroup.Title = 'Interpolation Method';
            app.NoneButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.NearestButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.NearestButton.Text = 'Nearest';
            app.BilinearButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.BilinearButton.Text = 'Bilinear';
            app.BicubicButton = uiradiobutton(app.InterpolationMethodButtonGroup);
            app.BicubicButton.Text = 'Bicubic';
            app.RestartButton = uibutton(app.ECE480ImageProcessingMidtermProjectPanel, 'push');
            app.RestartButton.ButtonPushedFcn = createCallbackFcn(app, @RestartButtonPushed, true);
            app.RestartButton.Text = 'Restart';
            app.UndoButton_2 = uibutton(app.ECE480ImageProcessingMidtermProjectPanel, 'push');
            app.UndoButton_2.ButtonPushedFcn = createCallbackFcn(app, @UndoButton_2Pushed, true);
            app.UndoButton_2.Text = 'Undo';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ece480_final_project_VER_1_1_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end